classdef appMetNum < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        tabPaneApp                      matlab.ui.container.TabGroup
        RacesdeunafuncinTab             matlab.ui.container.Tab
        b                               matlab.ui.control.EditField
        AproximacinInicialLabel_4       matlab.ui.control.Label
        a                               matlab.ui.control.EditField
        AproximacinInicialLabel_3       matlab.ui.control.Label
        RegulaFalsiLabel                matlab.ui.control.Label
        BiseccinLabel                   matlab.ui.control.Label
        xi_menos_1                      matlab.ui.control.EditField
        ToleranciaLabel_3               matlab.ui.control.Label
        xi                              matlab.ui.control.EditField
        AproximacinInicialLabel_2       matlab.ui.control.Label
        SecanteLabel                    matlab.ui.control.Label
        CalcularButton                  matlab.ui.control.Button
        maxIter                         matlab.ui.control.EditField
        ToleranciaLabel_2               matlab.ui.control.Label
        tol                             matlab.ui.control.EditField
        ToleranciaLabel                 matlab.ui.control.Label
        NewtonRaphsonLabel              matlab.ui.control.Label
        x0                              matlab.ui.control.EditField
        AproximacinInicialLabel         matlab.ui.control.Label
        funcion                         matlab.ui.control.EditField
        FuncinEditFieldLabel            matlab.ui.control.Label
        EncontrarlasracesdeLabel        matlab.ui.control.Label
        graficaFuncion                  matlab.ui.control.UIAxes
        SIstemasdeecuacineslinealesTab  matlab.ui.container.Tab
        DerivadaNumricaTab              matlab.ui.container.Tab
        OrdenDropDown                   matlab.ui.control.DropDown
        OrdenDropDownLabel              matlab.ui.control.Label
        hEditField                      matlab.ui.control.NumericEditField
        hEditFieldLabel                 matlab.ui.control.Label
        xiEditField                     matlab.ui.control.NumericEditField
        xiEditFieldLabel                matlab.ui.control.Label
        FuncionEditField                matlab.ui.control.EditField
        FuncionEditFieldLabel           matlab.ui.control.Label
        CentralLabel                    matlab.ui.control.Label
        RegresivaLabel                  matlab.ui.control.Label
        ProgresivaLabel                 matlab.ui.control.Label
        CalcularDerivadasButton         matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        IntegracinNumricaTab            matlab.ui.container.Tab
        n_intervalo                     matlab.ui.control.NumericEditField
        LmiteinferiorbLabel_2           matlab.ui.control.Label
        LmiteinferioraEditFieldLabel    matlab.ui.control.Label
        LmiteinferiorbLabel             matlab.ui.control.Label
        b_limite                        matlab.ui.control.NumericEditField
        a_limite                        matlab.ui.control.NumericEditField
        funcion_integral                matlab.ui.control.EditField
        AproximacinInicialLabel_5       matlab.ui.control.Label
        resultadoLabel                  matlab.ui.control.Label
        label                           matlab.ui.control.Label
        CalcularporelmtododeLabel       matlab.ui.control.Label
        CalcularRectanguloButton        matlab.ui.control.Button
        CalcularSimpsonButton           matlab.ui.control.Button
        CalcularTrapecioButton          matlab.ui.control.Button
        IntegracinnumricaLabel          matlab.ui.control.Label
        graficoTrapecio                 matlab.ui.control.UIAxes
        EcuacionesDiferencialesTab      matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            % --- Configurar variable simbólica y entrada del usuario ---
            syms x
            f = str2sym(app.funcion.Value);                      % Convierte el texto en expresión simbólica
            df = diff(f, x);                               % Deriva simbólicamente
            % --- Convertir funciones simbólicas a anónimas ---
            f_anon = matlabFunction(f);                    % @(x) ...
            df_anon = matlabFunction(df);                  % @(x) ...
            
            % --- Obtener y convertir valores numéricos de los campos de la GUI ---
            % ¡Asegúrate de que estas conversiones se hacen antes de usarlas!
            x0_numerico = str2double(app.x0.Value); % Aproximación inicial para Newton-Raphson
            tol_numerico = str2double(app.tol.Value); % Tolerancia para todos los métodos
            
            maxIter_texto = app.maxIter.Value;
            maxIter_numerico_double = str2double(maxIter_texto);
            
            % 3. Validar que sea un número entero positivo
            if isnan(maxIter_numerico_double) || maxIter_numerico_double <= 0 || mod(maxIter_numerico_double, 1) ~= 0
                uialert(app.UIFigure, 'El "Máximo de Iteraciones" debe ser un número entero positivo. Por favor, corrija la entrada.', 'Error de Entrada');
                return; % Detiene la ejecución del callback si hay error
            end
            maxIter_entero = int64(maxIter_numerico_double); 
            
            % --- Ejecutar Newton-Raphson ---
            [raiz_newton, iteraciones_newton, error_relativo_newton] = newtonRaphson(f_anon, df_anon, x0_numerico, tol_numerico, maxIter_entero);
            
            % --- Obtener valores para el método de la secante ---
            x_i_secante = str2double(app.xi.Value); % Segunda aproximación inicial para Secante
            xi_1_secante = str2double(app.xi_menos_1.Value); % Primera aproximación inicial para Secante
            % --- Ejecutar Método de la Secante ---
            % Nota: La función metodoSecante no recibe df_anon.
            % Asegúrate de que tu función metodoSecante solo espera 5 argumentos:
            % (funcion_anonima, x_i_menos_1, x_i, tolerancia, max_iteraciones)
            [raiz_secante, iteraciones_secante, error_relativo_secante] = metodoSecante(f_anon, xi_1_secante, x_i_secante, tol_numerico, maxIter_entero);
            
            % --- Obtener valores para el método de la bisección ---
            a_biseccion = str2double(app.a.Value); % Límite inferior para Bisección
            b_biseccion = str2double(app.b.Value); % Límite superior para Bisección

            % --- Ejecutar Método de Bisección ---
            % Asegúrate de que tu función biseccion solo espera 5 argumentos:
            % (funcion_anonima, a, b, tolerancia, max_iteraciones)
            [raiz_biseccion, iteraciones_biseccion, error_relativo_biseccion] = biseccion(f_anon, a_biseccion, b_biseccion, tol_numerico, maxIter_entero);
            
            % --- Graficar función ---
            cla(app.graficaFuncion)                       % Limpia el gráfico anterior
            
            % Rango de la gráfica: ajustado para incluir todas las raíces y un poco más
            % Puedes ajustar este rango para que se adapte mejor a tus funciones.
            min_x_plot = min([x0_numerico - 10, raiz_newton - 2, raiz_secante - 2, raiz_biseccion - 2]);
            max_x_plot = max([x0_numerico + 10, raiz_newton + 2, raiz_secante + 2, raiz_biseccion + 2]);
            fplot(app.graficaFuncion, f, [min_x_plot, max_x_plot], 'LineWidth', 1.5, 'DisplayName', 'f(x)')
            hold(app.graficaFuncion, 'on')
            
            % --- Marcar y etiquetar raíz de Newton-Raphson ---
            y_raiz_newton = f_anon(raiz_newton);
            plot(app.graficaFuncion, raiz_newton, y_raiz_newton, 'ro', 'MarkerSize', 8, 'LineWidth', 2, 'DisplayName', 'Raíz Newton-Raphson')
            text(app.graficaFuncion, raiz_newton, y_raiz_newton, sprintf('  Newton-Raphson (%.5f)', raiz_newton), ...
                 'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'left', 'Color', 'red', 'FontSize', 15);
            
            % --- Marcar y etiquetar raíz de la Secante ---
            y_raiz_secante = f_anon(raiz_secante);
            plot(app.graficaFuncion, raiz_secante, y_raiz_secante, 'bs', 'MarkerSize', 8, 'LineWidth', 2, 'DisplayName', 'Raíz Secante')
            text(app.graficaFuncion, raiz_secante, y_raiz_secante, sprintf('  Secante (%.5f)', raiz_secante), ...
                 'VerticalAlignment', 'top', 'HorizontalAlignment', 'left', 'Color', 'blue', 'FontSize', 15);
            
            % --- Marcar y etiquetar raíz de la Bisección ---
            y_raiz_biseccion = f_anon(raiz_biseccion);
            plot(app.graficaFuncion, raiz_biseccion, y_raiz_biseccion, 'gd', 'MarkerSize', 8, 'LineWidth', 2, 'DisplayName', 'Raíz Bisección') % 'gd' para un diamante verde
            text(app.graficaFuncion, raiz_biseccion, y_raiz_biseccion, sprintf('  Bisección (%.5f)', raiz_biseccion), ...
                 'VerticalAlignment', 'middle', 'HorizontalAlignment', 'right', 'Color', 'green', 'FontSize', 15); % Ajusta la alineación si se superpone
            
            % --- Estética del gráfico ---
            grid(app.graficaFuncion, 'on')
            xlabel(app.graficaFuncion, 'x')
            ylabel(app.graficaFuncion, 'f(x)')
            legend(app.graficaFuncion, 'show', 'Location', 'best'); % Muestra la leyenda
        end

        % Button pushed function: CalcularTrapecioButton
        function CalcularTrapecioButtonPushed(app, event)
            
        end

        % Button pushed function: CalcularSimpsonButton
        function CalcularSimpsonButtonPushed(app, event)
            try
                % Obtener función como string y convertir a función anónima
                func_str = app.funcion_integral.Value;
                f = str2func(['@(x)', func_str]);
        
                % Obtener límites y subintervalos
                a = app.a_limite.Value;
                b = app.b_limite.Value;
                n = app.n_intervalo.Value;
        
                % Calcular integración
                resultado = simpson13(f, a, b, n);
                
                % Mostrar resultado en label
                app.resultadoLabel.Text = ['Resultado: ', num2str(resultado)];
                
                % Graficar
                x = linspace(a, b, 1000);
                y = f(x);
                plot(app.graficoTrapecio, x, y, 'LineWidth', 2);
                title(app.graficoTrapecio, 'Gráfico de f(x)');
                xlabel(app.graficoTrapecio, 'x');
                ylabel(app.graficoTrapecio, 'f(x)');
                grid(app.graficoTrapecio, 'on');
            catch ME
                uialert(app.UIFigure, ME.message, 'Error');
            end
        end

        % Button pushed function: CalcularRectanguloButton
        function CalcularRectanguloButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.tabPaneApp = uitabgroup(app.UIFigure);
            app.tabPaneApp.Tag = 'tabPaneApp';
            app.RacesdeunafuncinTab = uitab(app.tabPaneApp);
            app.RacesdeunafuncinTab.Title = 'Raíces de una función';
            app.graficaFuncion = uiaxes(app.RacesdeunafuncinTab);
            title(app.graficaFuncion, 'Función y raíz aproximada por cada método')
            xlabel(app.graficaFuncion, 'X')
            ylabel(app.graficaFuncion, 'Y')
            zlabel(app.graficaFuncion, 'Z')
            app.EncontrarlasracesdeLabel = uilabel(app.RacesdeunafuncinTab);
            app.EncontrarlasracesdeLabel.Text = 'Encontrar las raíces de:';
            app.FuncinEditFieldLabel = uilabel(app.RacesdeunafuncinTab);
            app.FuncinEditFieldLabel.Text = 'Función';
            app.funcion = uieditfield(app.RacesdeunafuncinTab, 'text');
            app.AproximacinInicialLabel = uilabel(app.RacesdeunafuncinTab);
            app.AproximacinInicialLabel.Text = 'Aproximación Inicial';
            app.x0 = uieditfield(app.RacesdeunafuncinTab, 'text');
            app.NewtonRaphsonLabel = uilabel(app.RacesdeunafuncinTab);
            app.NewtonRaphsonLabel.Text = 'Newton-Raphson';
            app.ToleranciaLabel = uilabel(app.RacesdeunafuncinTab);
            app.ToleranciaLabel.Text = 'Tolerancia';
            app.tol = uieditfield(app.RacesdeunafuncinTab, 'text');
            app.ToleranciaLabel_2 = uilabel(app.RacesdeunafuncinTab);
            app.ToleranciaLabel_2.Text = 'Max. Iteraciones';
            app.maxIter = uieditfield(app.RacesdeunafuncinTab, 'text');
            app.CalcularButton = uibutton(app.RacesdeunafuncinTab, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.SecanteLabel = uilabel(app.RacesdeunafuncinTab);
            app.SecanteLabel.Text = 'Secante';
            app.AproximacinInicialLabel_2 = uilabel(app.RacesdeunafuncinTab);
            app.AproximacinInicialLabel_2.Text = 'Xi';
            app.xi = uieditfield(app.RacesdeunafuncinTab, 'text');
            app.ToleranciaLabel_3 = uilabel(app.RacesdeunafuncinTab);
            app.ToleranciaLabel_3.Text = 'Xi-1';
            app.xi_menos_1 = uieditfield(app.RacesdeunafuncinTab, 'text');
            app.BiseccinLabel = uilabel(app.RacesdeunafuncinTab);
            app.BiseccinLabel.Text = 'Bisección';
            app.RegulaFalsiLabel = uilabel(app.RacesdeunafuncinTab);
            app.RegulaFalsiLabel.Text = 'Regula-Falsi';
            app.AproximacinInicialLabel_3 = uilabel(app.RacesdeunafuncinTab);
            app.AproximacinInicialLabel_3.Text = 'a';
            app.a = uieditfield(app.RacesdeunafuncinTab, 'text');
            app.AproximacinInicialLabel_4 = uilabel(app.RacesdeunafuncinTab);
            app.AproximacinInicialLabel_4.Text = 'b';
            app.b = uieditfield(app.RacesdeunafuncinTab, 'text');
            app.SIstemasdeecuacineslinealesTab = uitab(app.tabPaneApp);
            app.SIstemasdeecuacineslinealesTab.Title = 'SIstemas de ecuaciónes lineales';
            app.DerivadaNumricaTab = uitab(app.tabPaneApp);
            app.DerivadaNumricaTab.Title = 'Derivada Numérica';
            app.UIAxes = uiaxes(app.DerivadaNumricaTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CalcularDerivadasButton = uibutton(app.DerivadaNumricaTab, 'push');
            app.CalcularDerivadasButton.Text = 'Calcular Derivadas';
            app.ProgresivaLabel = uilabel(app.DerivadaNumricaTab);
            app.ProgresivaLabel.Text = 'Progresiva:';
            app.RegresivaLabel = uilabel(app.DerivadaNumricaTab);
            app.RegresivaLabel.Text = 'Regresiva:';
            app.CentralLabel = uilabel(app.DerivadaNumricaTab);
            app.CentralLabel.Text = 'Central:';
            app.FuncionEditFieldLabel = uilabel(app.DerivadaNumricaTab);
            app.FuncionEditFieldLabel.Text = 'Funcion:';
            app.FuncionEditField = uieditfield(app.DerivadaNumricaTab, 'text');
            app.xiEditFieldLabel = uilabel(app.DerivadaNumricaTab);
            app.xiEditFieldLabel.Text = 'xi:';
            app.xiEditField = uieditfield(app.DerivadaNumricaTab, 'numeric');
            app.hEditFieldLabel = uilabel(app.DerivadaNumricaTab);
            app.hEditFieldLabel.Text = 'h:';
            app.hEditField = uieditfield(app.DerivadaNumricaTab, 'numeric');
            app.OrdenDropDownLabel = uilabel(app.DerivadaNumricaTab);
            app.OrdenDropDownLabel.Text = 'Orden:';
            app.OrdenDropDown = uidropdown(app.DerivadaNumricaTab);
            app.OrdenDropDown.Items = {'1', '2', '3'};
            app.OrdenDropDown.Value = '1';
            app.IntegracinNumricaTab = uitab(app.tabPaneApp);
            app.IntegracinNumricaTab.Title = 'Integración Numérica';
            app.graficoTrapecio = uiaxes(app.IntegracinNumricaTab);
            xlabel(app.graficoTrapecio, 'X')
            ylabel(app.graficoTrapecio, 'Y')
            zlabel(app.graficoTrapecio, 'Z')
            app.IntegracinnumricaLabel = uilabel(app.IntegracinNumricaTab);
            app.IntegracinnumricaLabel.Text = 'Integración numérica ';
            app.CalcularTrapecioButton = uibutton(app.IntegracinNumricaTab, 'push');
            app.CalcularTrapecioButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularTrapecioButtonPushed, true);
            app.CalcularTrapecioButton.Text = 'Trapecio';
            app.CalcularSimpsonButton = uibutton(app.IntegracinNumricaTab, 'push');
            app.CalcularSimpsonButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularSimpsonButtonPushed, true);
            app.CalcularSimpsonButton.Text = 'Regla de Simpson';
            app.CalcularRectanguloButton = uibutton(app.IntegracinNumricaTab, 'push');
            app.CalcularRectanguloButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularRectanguloButtonPushed, true);
            app.CalcularRectanguloButton.Text = 'Riemman';
            app.CalcularporelmtododeLabel = uilabel(app.IntegracinNumricaTab);
            app.CalcularporelmtododeLabel.Text = 'Calcular por el método de:';
            app.label = uilabel(app.IntegracinNumricaTab);
            app.label.Text = 'Resultado:';
            app.resultadoLabel = uilabel(app.IntegracinNumricaTab);
            app.resultadoLabel.Text = '';
            app.AproximacinInicialLabel_5 = uilabel(app.IntegracinNumricaTab);
            app.AproximacinInicialLabel_5.Text = 'Función integral:';
            app.funcion_integral = uieditfield(app.IntegracinNumricaTab, 'text');
            app.a_limite = uieditfield(app.IntegracinNumricaTab, 'numeric');
            app.b_limite = uieditfield(app.IntegracinNumricaTab, 'numeric');
            app.LmiteinferiorbLabel = uilabel(app.IntegracinNumricaTab);
            app.LmiteinferiorbLabel.Text = 'Límite inferior b';
            app.LmiteinferioraEditFieldLabel = uilabel(app.IntegracinNumricaTab);
            app.LmiteinferioraEditFieldLabel.Text = 'Límite inferior a';
            app.LmiteinferiorbLabel_2 = uilabel(app.IntegracinNumricaTab);
            app.LmiteinferiorbLabel_2.Text = 'Número de intervalos';
            app.n_intervalo = uieditfield(app.IntegracinNumricaTab, 'numeric');
            app.EcuacionesDiferencialesTab = uitab(app.tabPaneApp);
            app.EcuacionesDiferencialesTab.Title = 'Ecuaciones Diferenciales';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMetNum

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end